classdef OperacionesBasicas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SealresultadoLabel              matlab.ui.control.Label
        AtenuacinButton_h_3             matlab.ui.control.Button
        AmplificacinButton_h_3          matlab.ui.control.Button
        AtenuacinButton_h_2             matlab.ui.control.Button
        AmplificacinButton_h_2          matlab.ui.control.Button
        Laposicininicialempiezacontandoelprimerelementocomo0Label  matlab.ui.control.Label
        inicialEditField_hn             matlab.ui.control.NumericEditField
        PosicininicialEditField_2Label  matlab.ui.control.Label
        inicialEditField_xn             matlab.ui.control.NumericEditField
        PosicininicialEditFieldLabel_2  matlab.ui.control.Label
        GraficarhnButton                matlab.ui.control.Button
        GraficarxnButton                matlab.ui.control.Button
        Practica1OperacionesbasicasLabel  matlab.ui.control.Label
        DesplazamientoButton_h          matlab.ui.control.Button
        ReflejoButton_h                 matlab.ui.control.Button
        AtenuacinButton_h               matlab.ui.control.Button
        AmplificacinButton_h            matlab.ui.control.Button
        DiezmacinButton                 matlab.ui.control.Button
        InterpolacinButton              matlab.ui.control.Button
        ConvolucinButton                matlab.ui.control.Button
        SumaRestaButton                 matlab.ui.control.Button
        IntroduzcasussealesLabel        matlab.ui.control.Label
        xnEditField                     matlab.ui.control.EditField
        xnEditFieldLabel                matlab.ui.control.Label
        hnEditField                     matlab.ui.control.EditField
        hnEditFieldLabel                matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_h                        matlab.ui.control.UIAxes
        UIAxes_x                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarxnButton
        function GraficarxnButtonPushed(app, event)
             cla(app.UIAxes_x, 'reset');
             vec = str2double(strsplit(app.xnEditField.Value)); 
             inicial = app.inicialEditField_xn.Value;
             if (inicial < length(vec) && inicial >= 0)
                xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             %disp(xi);
             %disp(inicial);
             stem(app.UIAxes_x, xi, vec,'-r'); 
        end

        % Button pushed function: GraficarhnButton
        function GraficarhnButtonPushed(app, event)
             cla(app.UIAxes_h, 'reset');
             vec = str2double(strsplit(app.hnEditField.Value)); 
             inicial = app.inicialEditField_hn.Value;
             if (inicial < length(vec) && inicial >= 0)
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             else
                 inicial = 0;
                 xi = (-1 * inicial):length(vec)-(inicial + 1);
             end
             
             stem(app.UIAxes_h, xi, vec,'-b'); 
        end

        % Button pushed function: AmplificacinButton_h_2
        function AmplificacinButton_h_2Pushed(app, event)
            Signal = [ 1 1 1 ];
            maxS = max(Signal);
            minS = min(Signal);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_x = uiaxes(app.UIFigure);
            title(app.UIAxes_x, 'Señal x(n)')
            xlabel(app.UIAxes_x, 'n')
            ylabel(app.UIAxes_x, 'x(n)')
            zlabel(app.UIAxes_x, 'Z')
            app.UIAxes_h = uiaxes(app.UIFigure);
            title(app.UIAxes_h, 'Señal h(n)')
            xlabel(app.UIAxes_h, 'n')
            ylabel(app.UIAxes_h, 'h(n)')
            zlabel(app.UIAxes_h, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal F(n)')
            xlabel(app.UIAxes, 'n')
            ylabel(app.UIAxes, 'F(n)')
            zlabel(app.UIAxes, 'Z')
            app.hnEditFieldLabel = uilabel(app.UIFigure);
            app.hnEditFieldLabel.Text = 'h(n) = ';
            app.hnEditField = uieditfield(app.UIFigure, 'text');
            app.xnEditFieldLabel = uilabel(app.UIFigure);
            app.xnEditFieldLabel.Text = 'x(n) = ';
            app.xnEditField = uieditfield(app.UIFigure, 'text');
            app.IntroduzcasussealesLabel = uilabel(app.UIFigure);
            app.IntroduzcasussealesLabel.Text = 'Introduzca sus señales';
            app.SumaRestaButton = uibutton(app.UIFigure, 'push');
            app.SumaRestaButton.Text = 'Suma / Resta';
            app.ConvolucinButton = uibutton(app.UIFigure, 'push');
            app.ConvolucinButton.Text = 'Convolución';
            app.InterpolacinButton = uibutton(app.UIFigure, 'push');
            app.InterpolacinButton.Text = 'Interpolación';
            app.DiezmacinButton = uibutton(app.UIFigure, 'push');
            app.DiezmacinButton.Text = 'Diezmación ';
            app.AmplificacinButton_h = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h.Text = 'Amplificación';
            app.AtenuacinButton_h = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h.Text = 'Atenuación';
            app.ReflejoButton_h = uibutton(app.UIFigure, 'push');
            app.ReflejoButton_h.Text = 'Reflejo';
            app.DesplazamientoButton_h = uibutton(app.UIFigure, 'push');
            app.DesplazamientoButton_h.Text = 'Desplazamiento';
            app.Practica1OperacionesbasicasLabel = uilabel(app.UIFigure);
            app.Practica1OperacionesbasicasLabel.Text = 'Practica 1: Operaciones basicas';
            app.GraficarxnButton = uibutton(app.UIFigure, 'push');
            app.GraficarxnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarxnButtonPushed, true);
            app.GraficarxnButton.Text = 'Graficar x(n)';
            app.GraficarhnButton = uibutton(app.UIFigure, 'push');
            app.GraficarhnButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarhnButtonPushed, true);
            app.GraficarhnButton.Text = 'Graficar h(n)';
            app.PosicininicialEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PosicininicialEditFieldLabel_2.Text = 'Posición inicial =';
            app.inicialEditField_xn = uieditfield(app.UIFigure, 'numeric');
            app.PosicininicialEditField_2Label = uilabel(app.UIFigure);
            app.PosicininicialEditField_2Label.Text = 'Posición inicial =';
            app.inicialEditField_hn = uieditfield(app.UIFigure, 'numeric');
            app.Laposicininicialempiezacontandoelprimerelementocomo0Label = uilabel(app.UIFigure);
            app.Laposicininicialempiezacontandoelprimerelementocomo0Label.Text = '*Las posiciones iniciales empiezan contando al primer elemento como 0';
            app.AmplificacinButton_h_2 = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h_2.ButtonPushedFcn = createCallbackFcn(app, @AmplificacinButton_h_2Pushed, true);
            app.AmplificacinButton_h_2.Text = 'Amplificación';
            app.AtenuacinButton_h_2 = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h_2.Text = 'Atenuación';
            app.AmplificacinButton_h_3 = uibutton(app.UIFigure, 'push');
            app.AmplificacinButton_h_3.Text = 'Amplificación';
            app.AtenuacinButton_h_3 = uibutton(app.UIFigure, 'push');
            app.AtenuacinButton_h_3.Text = 'Atenuación';
            app.SealresultadoLabel = uilabel(app.UIFigure);
            app.SealresultadoLabel.Text = 'Señal resultado';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OperacionesBasicas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end